classdef galil_control < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FlapperControl_BetaUIFigure  matlab.ui.Figure
        degLabel                     matlab.ui.control.Label
        deg0pkLabel                  matlab.ui.control.Label
        HzLabel                      matlab.ui.control.Label
        WakeGeneratorControlLabel    matlab.ui.control.Label
        StatusLamp                   matlab.ui.control.Lamp
        StatusLampLabel              matlab.ui.control.Label
        HoldButton                   matlab.ui.control.Button
        HoldPosEditField             matlab.ui.control.NumericEditField
        HoldPosEditFieldLabel        matlab.ui.control.Label
        ResetButton                  matlab.ui.control.Button
        MotoroffButton               matlab.ui.control.Button
        StopButton                   matlab.ui.control.Button
        FlapButton                   matlab.ui.control.Button
        AmpEditField                 matlab.ui.control.NumericEditField
        AmpEditFieldLabel            matlab.ui.control.Label
        FreqEditField                matlab.ui.control.NumericEditField
        FreqEditFieldLabel           matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
           galil = actxserver("galil");

        % Set the Galil's address.
           galil.address = "192.168.1.20";
           galil.command("DPA=0");  
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
              galil = actxserver("galil");

        % Set the Galil's address.
              galil.address = "192.168.1.20";%Open connections dialog box
              galil.command("ST");
              app.StatusLamp.Color = [1.00,0.00,0.00];
        end

        % Button pushed function: MotoroffButton
        function MotoroffButtonPushed(app, event)
             galil = actxserver("galil");

        % Set the Galil's address.
             galil.address = "192.168.1.20";%Open connections dialog box
             galil.command("MOA");
             app.StatusLamp.Color = [0.15,0.15,0.15];
        end

        % Button pushed function: FlapButton
        function FlapButtonPushed(app, event)
            global flapping_amp ;
            global flapping_frequency ;
            dt = round(1/flapping_frequency/4*1024);
            amp = round(flapping_amp/360*8000);
            angFreq = 2*pi*flapping_frequency;
            spd = abs(round(amp*angFreq*sin(angFreq*1/flapping_frequency/4)));
            app.StatusLamp.Color = [0.00,1.00,0.00];
            
            
            % Connect to the Galil device.
                    galil = actxserver("galil");
            
                    % Set the Galil's address.
                    galil.address = "192.168.1.20";%Open connections dialog box
            galil.command("ST");
            %dmc = fileread('program1.dmc');
            dmc = fileread('Flap_v3beta.dmc');
            dmc = string(dmc);
            dmc = strrep(dmc, "timeholder",...
                    num2str(dt));
            dmc = strrep(dmc, "speedholder",...
                    num2str(spd));
            dmc = strrep(dmc, "ampholder",...
                    num2str(amp));    
                    % Load the program described by the .dmc file to the Galil device.
                    galil.programDownload(dmc);
                    galil.command("XQ");
        end

        % Value changed function: FreqEditField
        function FreqEditFieldValueChanged(app, event)
            global flapping_frequency;
            flapping_frequency = app.FreqEditField.Value;
            
        end

        % Value changed function: AmpEditField
        function AmpEditFieldValueChanged(app, event)
            global flapping_amp
            flapping_amp = app.AmpEditField.Value;
            
        end

        % Value changed function: HoldPosEditField
        function HoldPosEditFieldValueChanged(app, event)
            global HoldDeg
            HoldDeg = app.HoldPosEditField.Value;
            
        end

        % Button pushed function: HoldButton
        function HoldButtonPushed(app, event)
            global HoldDeg
            HoldPos = (-HoldDeg+90)/360*8000;
            app.StatusLamp.Color = [1.00,1.00,0.00];
            % Connect to the Galil device.
        galil = actxserver("galil");

        % Set the Galil's address.
        galil.address = "192.168.1.20";%Open connections dialog box
        galil.command("ST");
        %dmc = fileread('program1.dmc');
        dmc = fileread('Hold.dmc');
        
        dmc = string(dmc);     
        dmc = strrep(dmc, "holdposholder",...
                    num2str(HoldPos));
        % Load the program described by the .dmc file to the Galil device.
        galil.programDownload(dmc);
        galil.command("XQ");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FlapperControl_BetaUIFigure = uifigure('Visible', 'off');
            app.FlapperControl_BetaUIFigure.Name = 'Flapper Control_Beta';
            app.FlapperControl_BetaUIFigure.Tag = 'Beta';
            app.FreqEditFieldLabel = uilabel(app.FlapperControl_BetaUIFigure);
            app.FreqEditFieldLabel.Text = 'Freq';
            app.FreqEditField = uieditfield(app.FlapperControl_BetaUIFigure, 'numeric');
            app.FreqEditField.ValueChangedFcn = createCallbackFcn(app, @FreqEditFieldValueChanged, true);
            app.AmpEditFieldLabel = uilabel(app.FlapperControl_BetaUIFigure);
            app.AmpEditFieldLabel.Text = 'Amp';
            app.AmpEditField = uieditfield(app.FlapperControl_BetaUIFigure, 'numeric');
            app.AmpEditField.ValueChangedFcn = createCallbackFcn(app, @AmpEditFieldValueChanged, true);
            app.FlapButton = uibutton(app.FlapperControl_BetaUIFigure, 'push');
            app.FlapButton.ButtonPushedFcn = createCallbackFcn(app, @FlapButtonPushed, true);
            app.FlapButton.Text = 'Flap';
            app.StopButton = uibutton(app.FlapperControl_BetaUIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.MotoroffButton = uibutton(app.FlapperControl_BetaUIFigure, 'push');
            app.MotoroffButton.ButtonPushedFcn = createCallbackFcn(app, @MotoroffButtonPushed, true);
            app.MotoroffButton.Text = 'Motor off';
            app.ResetButton = uibutton(app.FlapperControl_BetaUIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.HoldPosEditFieldLabel = uilabel(app.FlapperControl_BetaUIFigure);
            app.HoldPosEditFieldLabel.Text = 'Hold Pos';
            app.HoldPosEditField = uieditfield(app.FlapperControl_BetaUIFigure, 'numeric');
            app.HoldPosEditField.ValueChangedFcn = createCallbackFcn(app, @HoldPosEditFieldValueChanged, true);
            app.HoldButton = uibutton(app.FlapperControl_BetaUIFigure, 'push');
            app.HoldButton.ButtonPushedFcn = createCallbackFcn(app, @HoldButtonPushed, true);
            app.HoldButton.Text = 'Hold';
            app.StatusLampLabel = uilabel(app.FlapperControl_BetaUIFigure);
            app.StatusLampLabel.Text = 'Status';
            app.StatusLamp = uilamp(app.FlapperControl_BetaUIFigure);
            app.WakeGeneratorControlLabel = uilabel(app.FlapperControl_BetaUIFigure);
            app.WakeGeneratorControlLabel.Text = 'Wake Generator Control';
            app.HzLabel = uilabel(app.FlapperControl_BetaUIFigure);
            app.HzLabel.Text = 'Hz';
            app.deg0pkLabel = uilabel(app.FlapperControl_BetaUIFigure);
            app.deg0pkLabel.Text = 'deg,0-pk';
            app.degLabel = uilabel(app.FlapperControl_BetaUIFigure);
            app.degLabel.Text = 'deg';
            % Show the figure after all components are created
            app.FlapperControl_BetaUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = galil_control

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FlapperControl_BetaUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FlapperControl_BetaUIFigure)
        end
    end
end